classdef GUI_TM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ZFModellTrucksTab            matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        TruckMakerSimulationAppTMSiLabel  matlab.ui.control.Label
        SimulationButtonGroup        matlab.ui.container.ButtonGroup
        OpenTMButton                 matlab.ui.control.Button
        RunROSButton                 matlab.ui.control.Button
        StartRvizButton              matlab.ui.control.Button
        RITZModelTab                 matlab.ui.container.Tab
        Image3                       matlab.ui.control.Image
        Image3_2                     matlab.ui.control.Image
        OpenGitfordownloadingtheRouteButton  matlab.ui.control.Button
        HelpTab                      matlab.ui.container.Tab
        KaanAydinLabel               matlab.ui.control.Label
        aydinkaanit21itdhbwravensburgdeLabel  matlab.ui.control.Label
        ClickforDocumentationButton  matlab.ui.control.Button
        ContactLabel                 matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        BusinessmailkaanaydinzfcomLabel  matlab.ui.control.Label
        TruckMakerGUILabel           matlab.ui.control.Label
    end

    % Properties for ROS
    properties (Access = private)
        ROSInitialized = false
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('C:\Studienarbeit\Bilder'));
            
            image1 = imread('C:\Studienarbeit\Bilder\Truck.PNG');
            imshow(image1, 'Parent', app.UIAxes1)
            ax = app.UIAxes1;
            ax.Box = 'off';

            image2 = imread('C:\Studienarbeit\Bilder\Truck.PNG');
            imshow(image2, 'Parent', app.UIAxes3)
            ax = app.UIAxes2;
            ax.Box = 'on';
            
            image3 = imread('C:\Studienarbeit\Bilder\SkizzeRITZ.PNG');
            imshow(image3, 'Parent', app.UIAxes3)
            ax = app.UIAxes3;
            ax.Box = 'on';
            
            % Add CarMaker path (replace with actual path)
            carmaker_path = 'C:\ProgramData\Microsoft\Windows\Start Menu\Programs\IPG\CarMaker 11.0.1';
            addpath(carmaker_path); 
        end

        % Selection changed function: SimulationButtonGroup
        function SimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationButtonGroup.SelectedObject;
            
        end

        % Button pushed function: OpenTMButton
        function OpenTMButtonPushed(app, event)
            % Start CarMaker test-run
            % Replace 'testrunname' with your actual test-run name
            % cmStartTestRun('C:\CM_Projects\Test_AES\Data\TestRun\Test_AES_Presentation');
            
           if ispc
           % Windows-specific command to open a new terminal and run the commands
               uialert(app.UIFigure, 'This action is intended for Unix-based systems.', 'Error');
           elseif ismac
           % macOS-specific command to open a new terminal and run the commands
               system('osascript -e ''tell application "Terminal" to do script "cd /opt/ipg/bin/; ./TM-11.0.1"''');
           elseif isunix
           % Linux-specific command to open a new terminal and run the commands
               system('gnome-terminal -- bash -c "cd /opt/ipg/bin/; ./TM-11.0.1; exec bash"');
           else
           % Error message for unsupported operating systems
               uialert(app.UIFigure, 'Unsupported operating system', 'Error');
           end
        end

        % Button pushed function: RunROSButton
        function RunROSButtonPushed(app, event)
            if ispc
                % Windows-specific command to open a new terminal and run roscore
                system('start cmd /k roscore');
            elseif ismac
                % macOS-specific command to open a new terminal and run roscore
                system('osascript -e ''tell application "Terminal" to do script "roscore"''');
            elseif isunix
                % Linux-specific command to open a new terminal and run roscore
                system('gnome-terminal -- bash -c "roscore; exec bash"');
            else
                % Error message for unsupported operating systems
                uialert(app.UIFigure, 'Unsupported operating system', 'Error');
            end
        end

        % Button pushed function: StartRvizButton
        function StartRvizButtonPushed(app, event)
            if ispc
                % Windows-specific command to open a new terminal and run roscore
                system('start cmd /k rviz');
            elseif ismac
                % macOS-specific command to open a new terminal and run roscore
                system('osascript -e ''tell application "Terminal" to do script "rviz"''');
            elseif isunix
                % Linux-specific command to open a new terminal and run roscore
                system('gnome-terminal -- bash -c "rviz; exec bash"');
            else
                % Error message for unsupported operating systems
                uialert(app.UIFigure, 'Unsupported operating system', 'Error');
            end
        end

        % Button pushed function: ClickforDocumentationButton
        function ClickforDocumentationButtonPushed(app, event)
            % Pfad zur PDF-Datei
            pdfFilePath = fullfile("C:\Studienarbeit\Studienarbeit_T3200_Kaan_Aydin.pdf");
            
            % Überprüfen, ob die Datei existiert
            if isfile(pdfFilePath)
                % Öffnen der PDF-Datei
                open("C:\Studienarbeit\Studienarbeit_T3200_Kaan_Aydin.pdf");
            else
                % Fehlermeldung, wenn die Datei nicht gefunden wurde
                uialert(app.UIFigure, 'Documentation could not be found')
            end
        end

        % Button pushed function: 
        % OpenGitfordownloadingtheRouteButton
        function OpenGitfordownloadingtheRouteButtonPushed(app, event)
            web('https://github.com/KaanAyd/Studienarbeit2024', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZFModellTrucksTab = uitab(app.TabGroup);
            app.ZFModellTrucksTab.Title = 'ZF Modell Trucks';
            app.Image = uiimage(app.ZFModellTrucksTab);
            app.Image.ImageSource = 'Truck.PNG';
            app.TruckMakerSimulationAppTMSiLabel = uilabel(app.ZFModellTrucksTab);
            app.TruckMakerSimulationAppTMSiLabel.Text = 'TruckMaker Simulation App (TMSi)';
            app.SimulationButtonGroup = uibuttongroup(app.ZFModellTrucksTab);
            app.SimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationButtonGroupSelectionChanged, true);
            app.SimulationButtonGroup.Title = 'Simulation';
            app.OpenTMButton = uibutton(app.SimulationButtonGroup, 'push');
            app.OpenTMButton.ButtonPushedFcn = createCallbackFcn(app, @OpenTMButtonPushed, true);
            app.OpenTMButton.Text = 'Open TM';
            app.RunROSButton = uibutton(app.SimulationButtonGroup, 'push');
            app.RunROSButton.ButtonPushedFcn = createCallbackFcn(app, @RunROSButtonPushed, true);
            app.RunROSButton.Text = 'Run ROS';
            app.StartRvizButton = uibutton(app.SimulationButtonGroup, 'push');
            app.StartRvizButton.ButtonPushedFcn = createCallbackFcn(app, @StartRvizButtonPushed, true);
            app.StartRvizButton.Text = 'Start Rviz';
            app.RITZModelTab = uitab(app.TabGroup);
            app.RITZModelTab.Title = 'RITZ Model';
            app.Image3 = uiimage(app.RITZModelTab);
            app.Image3.ImageSource = 'SkizzeRITZ.PNG';
            app.Image3_2 = uiimage(app.RITZModelTab);
            app.Image3_2.ImageSource = 'RaumdarstellungCM2.PNG';
            app.OpenGitfordownloadingtheRouteButton = uibutton(app.RITZModelTab, 'push');
            app.OpenGitfordownloadingtheRouteButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGitfordownloadingtheRouteButtonPushed, true);
            app.OpenGitfordownloadingtheRouteButton.Text = 'Open Git for downloading the Route';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.KaanAydinLabel = uilabel(app.HelpTab);
            app.KaanAydinLabel.Text = 'Kaan Aydin';
            app.aydinkaanit21itdhbwravensburgdeLabel = uilabel(app.HelpTab);
            app.aydinkaanit21itdhbwravensburgdeLabel.Text = 'aydin.kaan-it21@it.dhbw-ravensburg.de';
            app.ClickforDocumentationButton = uibutton(app.HelpTab, 'push');
            app.ClickforDocumentationButton.ButtonPushedFcn = createCallbackFcn(app, @ClickforDocumentationButtonPushed, true);
            app.ClickforDocumentationButton.Text = 'Click for Documentation';
            app.ContactLabel = uilabel(app.HelpTab);
            app.ContactLabel.Text = 'Contact';
            app.Image2 = uiimage(app.HelpTab);
            app.Image2.ImageSource = 'Truck.PNG';
            app.BusinessmailkaanaydinzfcomLabel = uilabel(app.HelpTab);
            app.BusinessmailkaanaydinzfcomLabel.Text = 'Business mail: kaan.aydin@zf.com';
            app.TruckMakerGUILabel = uilabel(app.UIFigure);
            app.TruckMakerGUILabel.Text = 'TruckMaker GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end